classdef manual_volume_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        LoadMenu          matlab.ui.container.Menu
        SaveMenu          matlab.ui.container.Menu
        UnloadMenu        matlab.ui.container.Menu
        ExitMenu          matlab.ui.container.Menu
        HelpMenu          matlab.ui.container.Menu
        UserGuideMenu     matlab.ui.container.Menu
        ShortKeysMenu     matlab.ui.container.Menu
        AboutMenu         matlab.ui.container.Menu
        GridLayout        matlab.ui.container.GridLayout
        FixedZIDLabel     matlab.ui.control.Label
        FixButton         matlab.ui.control.Button
        ZSpinner          matlab.ui.control.Spinner
        ZSpinnerLabel     matlab.ui.control.Label
        AlphaSlider       matlab.ui.control.Slider
        AlphaSliderLabel  matlab.ui.control.Label
        YSlider           matlab.ui.control.Slider
        YSliderLabel      matlab.ui.control.Label
        XSlider           matlab.ui.control.Slider
        XSliderLabel      matlab.ui.control.Label
        AngleKnob         matlab.ui.control.Knob
        AngleKnobLabel    matlab.ui.control.Label
        PropertiesLabel   matlab.ui.control.Label
        ModeButtonGroup   matlab.ui.container.ButtonGroup
        ZProjectButton    matlab.ui.control.ToggleButton
        RegisterButton    matlab.ui.control.ToggleButton
        ViewButton        matlab.ui.control.ToggleButton
        UIAxes            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fileName;
        fileDir;
        time;

        stackData;      % x*y*z image stack

        sizeX;          % X pixel number
        sizeY;          % Y pixel number
        sizeZ;          % Z plane number

        xShift;         % image shift x_shift pixels in x direction
        yShift;         % image shift y_shift pixels in y direction
        angleShift;     % image circle angle_shift around the center of the image
        alpha;          % the transparency of the live image

        zID;            % the current z plane
        workMode;       % 0:View, 1:Register, 2:Z Project
        isLoaded;       % true when a stack is successfully loaded, false when no stacks loaded.
        FixedZID;       % the id of the fixed z plane, 0 when unfixed
%         lockStep;
    end


    methods (Access = private)
%% Initialize

        % Initialize all components and factors
        function results = init_all(app)
            init_factors(app);
            init_components(app);
%             functionlock();
        end

        function results = init_components(app)
            app.UIAxes.Title.String = 'Image Unloaded';
            app.XSlider.Value = 0;
            app.YSlider.Value = 0;
            app.AlphaSlider.Value = 50;
            app.AngleKnob.Value = 0;
            app.ZSpinner.Step = 1;
            app.ZSpinner.Value = 0;
            app.ModeButtonGroup.SelectedObject = app.ViewButton;
            app.ZSpinner.Value = 0;
            app.ZSpinner.Step = 1;
            app.PropertiesLabel.Text = '';
        end

        function results = init_factors(app)
            app.fileName = [];
            app.fileDir = [];
            app.time = [];
            app.stackData = [];
            app.sizeX = 0;
            app.sizeY = 0;
            app.sizeZ = 0;
            app.xShift = 0;
            app.yShift = 0;
            app.angleShift = 0;
            app.alpha = 50;
            app.zID = 0;
            app.workMode = 0;
            app.isLoaded = false;
            app.FixedZID = 0;
            
        end

%% Access Control

%         function results = functionlock(app, lockStep)
%             
%         end


%% Loading

        % Load a 3D image(Modified from getbfmovieinfo.m Ver 1.20 by JXH)
        function loadingResult = loadimage(app, movFile)
            % Input: (FileDir) + FileName
            % Output: Loading result: 0,Failed; 1, Succeeded

            data = bfopen(movFile);

            metaData = data{1,4};
            app.sizeX = metaData.getPixelsSizeX(0).getValue();
            app.sizeY = metaData.getPixelsSizeY(0).getValue();
            app.sizeZ = metaData.getPixelsSizeZ(0).getValue();
            sizeC = metaData.getPixelsSizeC(0).getValue();
            sizeT = metaData.getPixelsSizeT(0).getValue();

            if sizeT * sizeC == 1 % ensure that it is a 3-D stack.
                
                dataCell = reshape(data{1,1}(:,1), [1, app.sizeZ]);
                dataMatTemp = cell2mat(dataCell);
                movData = reshape(dataMatTemp, [app.sizeX, app.sizeY, app.sizeZ]);% movData:x*y*z matrix
                
                splited = split(movFile,'.');
                format = splited{end};

                if strcmp(format,'ims')
                    metaData = data{1,2};
                    F = 'yyyy-MM-dd HH:mm:ss.SSS';
                    app.time = datetime(metaData.get('Global TimePoint1'), 'InputFormat', F, 'Format', F);
                end

                movData = flip(movData, 1); % flip the image upside down
                app.stackData = movData;

                loadingResult = 1;

            else
                warndlg('4/5D images unsupported yet.', 'Warning');
                loadingResult = 0;
            end


            return 
        end


%% Draw images in the UIAxes
        function results = drawimage(app, zID)
            switch app.workMode
                case 0 % View Mode
                    image(app.UIAxes, app.stackData(:,:,1))
%                     im_H = image(app.UIAxes_Main,...
%                 [1 round(app.images_info.width*app.viewing_scale)],...
%                 [1 round(app.images_info.height*app.viewing_scale)],...
%                 app.mov_frames(:,:,1));

                case 1 % Register Mode
                case 2 % Z Project Mode
                otherwise
                    warndlg('aha, a bug.', 'BUG');
            end
            disp('draw');
            
            
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init_all();
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            app.alpha = app.AlphaSlider.Value;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            app.xShift = app.XSlider.Value;
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            app.yShift = app.YSlider.Value;
        end

        % Value changed function: AngleKnob
        function AngleKnobValueChanged(app, event)
            app.angleShift = app.AngleKnob.Value;
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            app.zID = app.ZSpinner.Value;
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [name,path] = uigetfile('*.ims');
            if ~isequal(name,0)
                app.fileName = name;
                app.fileDir = path;
                app.UIAxes.Title.String = name;
                loadingResult = app.loadimage([path,name]);
                disp('Loading succeeded.');

                if loadingResult == 1
                    app.isLoaded = true;
                    app.zID = 1;
                    app.drawimage(app);
                    
                else
                    warndlg('Loading failed.', 'Warning');
                end

                
            else
                warndlg('No File Selected', 'Warning');
            end
            
        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            app.FixedZID = app.ZSpinner.Value;
            app.FixedZIDLabel.Text = ['Fixed Z ID: ', num2str(app.FixedZID)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.UnloadMenu = uimenu(app.FileMenu);
            app.UnloadMenu.Text = 'Unload';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UserGuideMenu = uimenu(app.HelpMenu);
            app.UserGuideMenu.Text = 'UserGuide';
            app.ShortKeysMenu = uimenu(app.HelpMenu);
            app.ShortKeysMenu.Text = 'Short Keys';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'ImageName')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ModeButtonGroup = uibuttongroup(app.GridLayout);
            app.ModeButtonGroup.Title = 'Mode';
            app.ViewButton = uitogglebutton(app.ModeButtonGroup);
            app.ViewButton.Text = 'View';
            app.ViewButton.Value = true;
            app.RegisterButton = uitogglebutton(app.ModeButtonGroup);
            app.RegisterButton.Text = 'Register';
            app.ZProjectButton = uitogglebutton(app.ModeButtonGroup);
            app.ZProjectButton.Text = 'Z Project';
            app.PropertiesLabel = uilabel(app.GridLayout);
            app.PropertiesLabel.Text = 'Properties';
            app.AngleKnobLabel = uilabel(app.GridLayout);
            app.AngleKnobLabel.Text = 'Angle';
            app.AngleKnob = uiknob(app.GridLayout, 'continuous');
            app.AngleKnob.ValueChangedFcn = createCallbackFcn(app, @AngleKnobValueChanged, true);
            app.XSliderLabel = uilabel(app.GridLayout);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.GridLayout);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.GridLayout);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.GridLayout);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.AlphaSliderLabel = uilabel(app.GridLayout);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.GridLayout);
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 50;
            app.ZSpinnerLabel = uilabel(app.GridLayout);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.GridLayout);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.FixButton = uibutton(app.GridLayout, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            app.FixedZIDLabel = uilabel(app.GridLayout);
            app.FixedZIDLabel.Text = 'Fixed Z ID:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_volume_register

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end